classdef ppg2abp_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ui          matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        pnlSetup    matlab.ui.container.Panel
        lblStatus   matlab.ui.control.Label
        lmpStatus   matlab.ui.control.Lamp
        btnStop     matlab.ui.control.Button
        btnStart    matlab.ui.control.Button
        pnlAxes     matlab.ui.container.Panel
        pnlAxABP    matlab.ui.container.Panel
        axABP       matlab.ui.control.UIAxes
        pnlAxPPG    matlab.ui.container.Panel
        axPPG       matlab.ui.control.UIAxes
        pnlStats    matlab.ui.container.Panel
        pnlRR       matlab.ui.container.Panel
        txtRR       matlab.ui.control.Label
        lblRR       matlab.ui.control.Label
        pnlHR       matlab.ui.container.Panel
        txtHR       matlab.ui.control.Label
        lblHR       matlab.ui.control.Label
        pnlSpO2     matlab.ui.container.Panel
        txtSpO2     matlab.ui.control.Label
        lblSpO2     matlab.ui.control.Label
        pnlDBP      matlab.ui.container.Panel
        txtDBP      matlab.ui.control.Label
        lblDBP      matlab.ui.control.Label
        pnlSBP      matlab.ui.container.Panel
        txtSBP      matlab.ui.control.Label
        lblSBP      matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        arduinoObj; % Arduino serial object
        
        allPPG; % store all ppg data
        allABP; % store all ABP waveforms
        allABPd; % store all ABP discrete vals
        
        ppg = struct;
        abp = struct;
        
        sbp;
        dbp;
        
        Fs = 125;
        
        Ts = 1/125;
        
        displayDur = 10;
        
        dataLength;
        
        status;
        
        ppgPlot;
        abpPlot;
        
        Fs_abp = 25;
        Ts_abp = 1/25;
        abpLength;
        
        waveNet;
        discreteNet;
        
        spO2;
        heartRate;
        respRate;
    end
    
    methods (Access = public)
        
        function initialiseData(app)
            app.dataLength = length(0:app.Ts:(app.displayDur-app.Ts));
            app.ppg.redData = nan(app.dataLength,1);
            app.ppg.irData = nan(app.dataLength,1);
            app.ppg.tData =  zeros(app.dataLength,1);
            app.ppg.ppg = nan(app.dataLength,1);
            
            app.abpLength = length(0:app.Ts_abp:(app.displayDur-app.Ts_abp));
            
            app.abp.t = zeros(app.abpLength,1);
            app.abp.abp = nan(app.abpLength,1);
            
            app.allPPG = [];
            app.allABP = [];
            
            app.sbp = zeros(app.displayDur);
            app.dbp = zeros(app.displayDur);
            app.spO2 = zeros(app.displayDur);
            app.heartRate = zeros(app.displayDur);
            app.respRate = zeros(app.displayDur);
            
            addpath('../../matlab')
            addpath('../../matlab/TimeFeatures/')
            addpath('..');
            addSubPaths();
            
        end
        
        function read(app)
            dataCount = 0;
            t = 0;
           
            while (app.status == 1)
                
                newData = readPPG(app.arduinoObj);
    
                numData = height(newData);
                dataCount = dataCount + numData;
                

                if (dataCount < app.dataLength)
                    app.ppg.tData(dataCount-numData+1:dataCount) =  t;
                    app.ppg.redData(dataCount-numData+1:dataCount) =  newData(:,1);
                    app.ppg.irData(dataCount-numData+1:dataCount) =  newData(:,2);
                else
                    app.ppg.tData = [app.ppg.tData(numData+1:end); t];
                    app.ppg.redData = [app.ppg.redData(numData+1:end); newData(:,1)];
                    app.ppg.irData = [app.ppg.irData(numData+1:end); newData(:,2)];
                end

                t = t + app.Ts;

                % filter data // extract pulsatile component
                
                if (mod(dataCount, app.Fs) == 0) % i.e. every second
                    
                    % process PPG
                    
                    if (dataCount < app.dataLength)
                        [ppg_, spO2_] = getPPGfromChannels(...
                            app.ppg.redData(dataCount-app.Fs+1:dataCount),...
                            app.ppg.irData(dataCount-app.Fs+1:dataCount));
                        
                        if (dataCount > 5 * app.Fs)

                            app.ppg.ppg(dataCount-app.Fs+1:dataCount) = ppg_;

                            app.spO2(floor(dataCount/app.Fs)) = spO2_;

                            app.respRate(floor(dataCount/app.Fs)) = getRespiratoryRateFreq(app.ppg.tData(dataCount-app.Fs+1:dataCount), ppg_)*60;
                            [sys,dias,feet] = findPPGPeaks(ppg_, app.Fs);
                            sortedFeatures = sortPPGPeaks(sys, dias, feet);
                            app.heartRate(floor(dataCount/app.Fs)) = heartRateFromPPG(sortedFeatures(:,1), sortedFeatures(:,4),app.Fs);
                        end
                    else
                        
                        [ppg_, spO2_] = getPPGfromChannels(...
                            app.ppg.redData(end-app.Fs+1:end),...
                            app.ppg.irData(end-app.Fs+1:end));
                   
                        
                        app.ppg.ppg = [app.ppg.ppg(app.Fs+1:end); ppg_];

                        if (dataCount > 5 * app.Fs)
                            app.spO2 = [app.spO2(2:end); spO2_];

                            app.respRate = [app.respRate(2:end); getRespiratoryRateFreq(app.ppg.tData(dataCount-app.Fs+1:dataCount), ppg_)*60];
                            [sys,dias,feet] = findPPGPeaks(ppg_, app.Fs);
                            sortedFeatures = sortPPGPeaks(sys, dias, feet);
                            app.heartRate = [app.heartRate(2:end); heartRateFromPPG(sortedFeatures(:,1), sortedFeatures(:,4),app.Fs)];
                        end
                    end


                    % abp determination

                    % filter/fix parts of ABP that are strange

                    % update both PPG and ABP signal
                    refreshdata(app.axPPG,'caller')
                    drawnow limitrate
                end
                
                if (mod(dataCount,10*app.Fs) == 0)
                    app.txtHR.Text = mean(app.heartRate);
                    app.txtRR.Text = mean(app.respRate);
                    app.txtSpO2.Text = mean(app.spO2);
                end
                
            end
                
        end
        
        function initialisePlots(app)
            app.ppgPlot = plot(app.axPPG,app.ppg.tData, app.ppg.redData);
            app.ppgPlot.XDataSource = 'app.ppg.tData';
            app.ppgPlot.YDataSource = 'app.ppg.ppg';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnStart
        function btnStartButtonPushed(app, event)
            BAUD_RATE = 115200;
            COM_PORT = "COM10";
            msg = "Connecting...";
            
            app.lmpStatus.Color = "blue";
            
            app.lblStatus.Text = msg;
            
            [app.arduinoObj, successFlag] = initConnection(COM_PORT, BAUD_RATE);
            
            if (successFlag == -1)
                msg = "Connection with device cannot be established!";
                errordlg(msg);
                app.lmpStatus.Color = "red";
                app.lblStatus.Text = msg;
            end

            if (successFlag == 0)
                msg = "Device failure.";
                errordlg(msg);
                app.lmpStatus.Color = "red";
                app.lblStatus.Text = msg;
            end

            if (successFlag == 1)
                msg = "Connected";
                app.lmpStatus.Color = "green";
                app.status = 1;
                
                app.lblStatus.Text = msg;
                
                app.initialiseData();
                
                app.initialisePlots();
                
                app.read();
            end
            
            
        end

        % Button pushed function: btnStop
        function btnStopButtonPushed(app, event)
            app.status = 0;
            
            app.lmpStatus.Color = "#cccccc";
            app.lblStatus.Text = "Click START";
            
            stopReading(app.arduinoObj);
            
            app.arduinoObj = [];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ui.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {488, 488, 488};
                app.GridLayout.ColumnWidth = {'1x'};
                app.pnlAxes.Layout.Row = 1;
                app.pnlAxes.Layout.Column = 1;
                app.pnlSetup.Layout.Row = 2;
                app.pnlSetup.Layout.Column = 1;
                app.pnlStats.Layout.Row = 3;
                app.pnlStats.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {488, 488};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.pnlAxes.Layout.Row = 1;
                app.pnlAxes.Layout.Column = [1,2];
                app.pnlSetup.Layout.Row = 2;
                app.pnlSetup.Layout.Column = 1;
                app.pnlStats.Layout.Row = 2;
                app.pnlStats.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {183, '1x', 177};
                app.pnlSetup.Layout.Row = 1;
                app.pnlSetup.Layout.Column = 1;
                app.pnlAxes.Layout.Row = 1;
                app.pnlAxes.Layout.Column = 2;
                app.pnlStats.Layout.Row = 1;
                app.pnlStats.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ui = uifigure('Visible', 'off');
            app.ui.Name = 'Smart Sensing of Blood Pressure via Photoplethysmography';
            app.ui.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ui);
            app.pnlSetup = uipanel(app.GridLayout);
            app.btnStart = uibutton(app.pnlSetup, 'push');
            app.btnStart.ButtonPushedFcn = createCallbackFcn(app, @btnStartButtonPushed, true);
            app.btnStart.Text = 'START';
            app.btnStop = uibutton(app.pnlSetup, 'push');
            app.btnStop.ButtonPushedFcn = createCallbackFcn(app, @btnStopButtonPushed, true);
            app.btnStop.Text = 'STOP';
            app.lmpStatus = uilamp(app.pnlSetup);
            app.lblStatus = uilabel(app.pnlSetup);
            app.lblStatus.Text = 'Click START';
            app.pnlAxes = uipanel(app.GridLayout);
            app.pnlAxPPG = uipanel(app.pnlAxes);
            app.axPPG = uiaxes(app.pnlAxPPG);
            title(app.axPPG, 'PHOTOPLETHYSMOGRAPH (PPG)')
            xlabel(app.axPPG, 'TIME | s')
            ylabel(app.axPPG, 'PPG')
            zlabel(app.axPPG, 'Z')
            app.pnlAxABP = uipanel(app.pnlAxes);
            app.axABP = uiaxes(app.pnlAxABP);
            title(app.axABP, 'ARTERIAL BLOOD PRESSURE (ABP)')
            xlabel(app.axABP, 'TIME | s')
            ylabel(app.axABP, 'PRESSURE | mmHg')
            zlabel(app.axABP, 'Z')
            app.pnlStats = uipanel(app.GridLayout);
            app.pnlSBP = uipanel(app.pnlStats);
            app.lblSBP = uilabel(app.pnlSBP);
            app.lblSBP.Text = 'SBP | mmHg';
            app.txtSBP = uilabel(app.pnlSBP);
            app.txtSBP.Text = '--';
            app.pnlDBP = uipanel(app.pnlStats);
            app.lblDBP = uilabel(app.pnlDBP);
            app.lblDBP.Text = 'DBP | mmHg';
            app.txtDBP = uilabel(app.pnlDBP);
            app.txtDBP.Text = '--';
            app.pnlSpO2 = uipanel(app.pnlStats);
            app.lblSpO2 = uilabel(app.pnlSpO2);
            app.lblSpO2.Text = 'SPO2 | %';
            app.txtSpO2 = uilabel(app.pnlSpO2);
            app.txtSpO2.Text = '--';
            app.pnlHR = uipanel(app.pnlStats);
            app.lblHR = uilabel(app.pnlHR);
            app.lblHR.Text = 'HR | bpm';
            app.txtHR = uilabel(app.pnlHR);
            app.txtHR.Text = '--';
            app.pnlRR = uipanel(app.pnlStats);
            app.lblRR = uilabel(app.pnlRR);
            app.lblRR.Text = 'RR | bpm';
            app.txtRR = uilabel(app.pnlRR);
            app.txtRR.Text = '--';
            % Show the figure after all components are created
            app.ui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ppg2abp_gui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ui)
            else

                % Focus the running singleton app
                figure(runningApp.ui)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ui)
        end
    end
end